classdef ForecastingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SmartIndiaHackathonLabel   matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        AboutTab                   matlab.ui.container.Tab
        Label                      matlab.ui.control.Label
        LiveForecastTab            matlab.ui.container.Tab
        Panel_2                    matlab.ui.container.Panel
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes2_2                  matlab.ui.control.UIAxes
        Button_3                   matlab.ui.control.Button
        PREDICTEDWEATHERDATALabel  matlab.ui.control.Label
        Button_4                   matlab.ui.control.Button
        PREDICTEDLOADDATALabel     matlab.ui.control.Label
        Button_5                   matlab.ui.control.Button
        SCHEDULINGLabel            matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        RenewableEnergyForecastingforanIntegratedSmartGridLabel  matlab.ui.control.Label
        TeamDigitosLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        t = []% Description
        x=[]
        y=[]
        labels
        load
        scheduleComplete % Description
        genOne % Description
        genTwo % Description
        weatherTablePredicted % Description
        solarPrediction % Description
        windPrediction % Description
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t= readtable("wind.csv");
            app.scheduleComplete= readtable("Scheduling.xlsx");
            app.weatherTablePredicted=readtable("weatherPredicted.xlsx");
            app.load=table2array(readtable("loadPrediction.xlsx"));
           

        end

        % Callback function
        function windPlot(app, event)
            app.UIAxes.Visible = 'on';
            app.x=datetime(table2array(app.t(:,"date")));
            app.y= table2array(app.t(:,"windCapacity"));
            %hold(app.UIAxes,'on')
            scatter(app.UIAxes,app.x ,app.y);
        end

        % Button pushed function: Button_3
        function predictedWeather(app, event)
            app.UIAxes2.Visible = 'on';
            app.solarPrediction=table2array(app.weatherTablePredicted(:,"irradiance"));
            plot(app.UIAxes2,(1:24),app.solarPrediction);
            title(app.UIAxes2, 'Predicted Solar Irradiance Values')
            xlabel(app.UIAxes2, 'hours')
            ylabel(app.UIAxes2, 'Solar Irradiance')
            
            app.UIAxes2_2.Visible = 'on';
            app.windPrediction=table2array(app.weatherTablePredicted(:,"windspeed"));
            plot(app.UIAxes2_2,(1:24),app.windPrediction);
            title(app.UIAxes2_2, 'Predicted Wind Speed Values ')
            xlabel(app.UIAxes2_2, 'hours')
            ylabel(app.UIAxes2_2, 'Wind Speed')
        end

        % Button pushed function: Button_4
        function predictedLoad(app, event)
            app.UIAxes2_2.Visible = 'off';     
            app.UIAxes2.Visible = 'on';
            plot(app.UIAxes2_2,[0,1],[0,0]);
            plot(app.UIAxes2,(1:24),app.load);
            title(app.UIAxes2, 'Predicted Load Data - Jupiter Notebook')
            xlabel(app.UIAxes2, 'hours')
            ylabel(app.UIAxes2, 'Total Demand')
        end

        % Button pushed function: Button_5
        function Scheduling(app, event)
            app.UIAxes2.Visible = 'on';
            app.genOne=table2array(app.scheduleComplete(:,"genOne"));
            bar(app.UIAxes2,(1:24),app.genOne);
            title(app.UIAxes2, 'Generator 1')
            xlabel(app.UIAxes2, 'hours')
            ylabel(app.UIAxes2, 'Generated Power')
            
            app.UIAxes2_2.Visible = 'on';
            app.genTwo=table2array(app.scheduleComplete(:,"genTwo"));
            bar(app.UIAxes2_2,(1:24),app.genTwo);
            title(app.UIAxes2_2, 'Generator 2')
            xlabel(app.UIAxes2_2, 'hours')
            ylabel(app.UIAxes2_2, 'Generated Power')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SmartIndiaHackathonLabel = uilabel(app.UIFigure);
            app.SmartIndiaHackathonLabel.Text = 'Smart India Hackathon';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Label = uilabel(app.AboutTab);
            app.Label.Text = {'Team: Digitos'; 'College: Thangal Kunju Musalilar College of Engineering, Kollam'; ''; 'TEAM MATES:'; 'Sona Subair'; 'J Sanjeev'; 'Varun S Prakash'; 'Pooja Sunil'; 'Antony John Puthen'; 'Suhail A'; ''; 'MENTORS'; 'Shyba S'; 'Muhammedali Shafeeque'; ''; ''};
            app.LiveForecastTab = uitab(app.TabGroup);
            app.LiveForecastTab.Title = 'Live Forecast';
            app.Panel_2 = uipanel(app.LiveForecastTab);
            app.Panel_2.Title = ' ';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.Visible = 'off';
            app.UIAxes2_2 = uiaxes(app.Panel_2);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes2_2.Visible = 'off';
            app.Button_3 = uibutton(app.LiveForecastTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @predictedWeather, true);
            app.Button_3.Text = '';
            app.PREDICTEDWEATHERDATALabel = uilabel(app.LiveForecastTab);
            app.PREDICTEDWEATHERDATALabel.Text = 'PREDICTED WEATHER DATA';
            app.Button_4 = uibutton(app.LiveForecastTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @predictedLoad, true);
            app.Button_4.Text = '';
            app.PREDICTEDLOADDATALabel = uilabel(app.LiveForecastTab);
            app.PREDICTEDLOADDATALabel.Text = 'PREDICTED LOAD DATA';
            app.Button_5 = uibutton(app.LiveForecastTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Scheduling, true);
            app.Button_5.Text = '';
            app.SCHEDULINGLabel = uilabel(app.LiveForecastTab);
            app.SCHEDULINGLabel.Text = 'SCHEDULING ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'sihlogopng.png';
            app.RenewableEnergyForecastingforanIntegratedSmartGridLabel = uilabel(app.UIFigure);
            app.RenewableEnergyForecastingforanIntegratedSmartGridLabel.Text = 'Renewable Energy Forecasting, for an Integrated Smart Grid';
            app.TeamDigitosLabel = uilabel(app.UIFigure);
            app.TeamDigitosLabel.Text = 'Team: Digitos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForecastingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end